classdef plant_analysis_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CreateparameterfilesButton      matlab.ui.control.Button
        ParameterDropDownLabel          matlab.ui.control.Label
        ParameterDropDown               matlab.ui.control.DropDown
        ImageHeightEditFieldLabel       matlab.ui.control.Label
        ImageHeightEditField            matlab.ui.control.NumericEditField
        RightxboundEditFieldLabel       matlab.ui.control.Label
        RightXboundEditField            matlab.ui.control.NumericEditField
        FrontyboundEditFieldLabel       matlab.ui.control.Label
        BottomZboundEditField           matlab.ui.control.NumericEditField
        BackyboundEditFieldLabel        matlab.ui.control.Label
        TopZboundEditField              matlab.ui.control.NumericEditField
        TextparamEditFieldLabel         matlab.ui.control.Label
        TextparamEditField              matlab.ui.control.EditField
        GenerateparameterfilesButton    matlab.ui.control.Button
        booleanDropDownLabel            matlab.ui.control.Label
        booleanDropDown                 matlab.ui.control.DropDown
        LeftxboundEditFieldLabel        matlab.ui.control.Label
        LeftXboundEditField             matlab.ui.control.NumericEditField
        ViewdepthimageforboundingboxButton  matlab.ui.control.Button
        AnalysemultipleplantsovertimeButton  matlab.ui.control.Button
        AnalyseallplantsovertimeButton  matlab.ui.control.Button
        PlotmeasurementsButton          matlab.ui.control.Button
        PlotallmeasurementsButton       matlab.ui.control.Button
        AnalysesingleplantButton        matlab.ui.control.Button
        ViewpointcloudButton            matlab.ui.control.Button
        ViewfulldepthimageButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        im_height = 424;
        im_width = 512;
        im_no = 11;
        plant_axis = 'y';
        do_remove_plane = true;
        do_remove_pot = true;
        bounding_box = [250, 1500, 1, 1500];
        save_pc = true;
        save_measurements = true;
        % TODO make background_distance a parameter
        background_distance = 2000;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
        end

        % Button pushed function: CreateparameterfilesButton
        function CreateparameterfilesButtonPushed(app, event)
            app.CreateparameterfilesButton.Visible = 'off';
            
            app.AnalyseallplantsovertimeButton.Visible = 'off';
            app.AnalysemultipleplantsovertimeButton.Visible = 'off';            
            app.AnalysesingleplantButton.Visible = 'off';
            
            app.PlotmeasurementsButton.Visible = 'off';
            app.PlotallmeasurementsButton.Visible = 'off';
            
            app.GenerateparameterfilesButton.Visible = 'on';
            
            app.ParameterDropDown.Visible = 'on';
            app.ParameterDropDownLabel.Visible = 'on';
            
            app.ImageHeightEditField.Visible = 'on';
            app.ImageHeightEditFieldLabel.Visible = 'on';
        end

        % Button pushed function: GenerateparameterfilesButton
        function GenerateparameterfilesButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose a top level folder to generate files for all its bottom level folders');
            
            drawnow;
            figure(app.UIFigure)
            
            end_paths = get_end_paths(folder_path, {});
            
            for i = 1:numel(end_paths)
                path = end_paths{i};
                generate_parameter_file(path, app.im_height, app.im_width, ...
                    app.im_no, app.plant_axis, app.do_remove_plane, app.do_remove_pot, ...
                    app.bounding_box, app.save_pc, app.save_measurements);  
            end     
            
            app.CreateparameterfilesButton.Visible = 'on';
            
            app.AnalyseallplantsovertimeButton.Visible = 'on';
            app.AnalysemultipleplantsovertimeButton.Visible = 'on';
            app.AnalysesingleplantButton.Visible = 'on';
            
            app.PlotmeasurementsButton.Visible = 'on';
            app.PlotallmeasurementsButton.Visible = 'on';
            
            app.GenerateparameterfilesButton.Visible = 'off';
            
            app.ParameterDropDown.Visible = 'off';
            app.ParameterDropDownLabel.Visible = 'off';
            
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditFieldLabel.Visible = 'off';
            
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditFieldLabel.Visible = 'off';
            
            app.booleanDropDown.Visible = 'off';
            app.booleanDropDownLabel.Visible = 'off';
                
            app.TextparamEditField.Visible = 'off';
            app.TextparamEditFieldLabel.Visible = 'off';
            
            app.RightXboundEditField.Visible = 'off';
            app.RightxboundEditFieldLabel.Visible = 'off';
            
            app.LeftXboundEditField.Visible = 'off';
            app.LeftxboundEditFieldLabel.Visible = 'off';
            
            app.BottomZboundEditField.Visible = 'off';
            app.FrontyboundEditFieldLabel.Visible = 'off';
            
            app.TopZboundEditField.Visible = 'off';
            app.BackyboundEditFieldLabel.Visible = 'off';
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            value = app.ParameterDropDown.Value;       
                
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditFieldLabel.Visible = 'off';
            
            app.booleanDropDown.Visible = 'off';
            app.booleanDropDownLabel.Visible = 'off';
                
            app.TextparamEditField.Visible = 'off';
            app.TextparamEditFieldLabel.Visible = 'off';
            
            app.RightXboundEditField.Visible = 'off';
            app.RightxboundEditFieldLabel.Visible = 'off';
            
            app.LeftXboundEditField.Visible = 'off';
            app.LeftxboundEditFieldLabel.Visible = 'off';
            
            app.BottomZboundEditField.Visible = 'off';
            app.FrontyboundEditFieldLabel.Visible = 'off';
            
            app.TopZboundEditField.Visible = 'off';
            app.BackyboundEditFieldLabel.Visible = 'off';
            
            if strcmp(value, 'Vertical axis')
                app.TextparamEditFieldLabel.Text = value;
                
                app.TextparamEditField.Visible = 'on';
                app.TextparamEditFieldLabel.Visible = 'on';
                
                app.TextparamEditField.Value = app.plant_axis;
                
            elseif strcmp(value, 'Remove plane') || strcmp(value, 'Remove pot') ...
                    || strcmp(value, 'Save point cloud') || strcmp(value, 'Save measurements')
                
                app.booleanDropDownLabel.Text = value;
                
                app.booleanDropDown.Visible = 'on';
                app.booleanDropDownLabel.Visible = 'on';
                
            elseif strcmp(value, 'Bounding box')                    
                    app.LeftXboundEditField.Visible = 'on';
                    app.LeftxboundEditFieldLabel.Visible = 'on';
                    app.LeftXboundEditField.Value = app.bounding_box(1);
                    
                    app.RightXboundEditField.Visible = 'on';
                    app.RightxboundEditFieldLabel.Visible = 'on';
                    app.RightXboundEditField.Value = app.bounding_box(2);
                    
                    app.BottomZboundEditField.Visible = 'on';
                    app.FrontyboundEditFieldLabel.Visible = 'on';
                    app.BottomZboundEditField.Value = app.bounding_box(3);
                    
                    app.TopZboundEditField.Visible = 'on';
                    app.BackyboundEditFieldLabel.Visible = 'on';
                    app.TopZboundEditField.Value = app.bounding_box(4);
                    
            else                    
                app.ImageHeightEditFieldLabel.Text = value;
                
                app.ImageHeightEditField.Visible = 'on';
                app.ImageHeightEditFieldLabel.Visible = 'on';
                
                if strcmp(value, 'Image Height')
                    app.ImageHeightEditField.Value = app.im_height;
                    
                elseif strcmp(value, 'Image Width')
                    app.ImageHeightEditField.Value = app.im_width;
                
                elseif strcmp(value, 'Number of views')
                    app.ImageHeightEditField.Value = app.im_no;
                end
            end
        end

        % Button pushed function: ViewfulldepthimageButton
        function ViewfulldepthimageButtonPushed(app, event)
            [file_name, file_path] = uigetfile('*.png', 'Select a depth image');
            
            drawnow;
            figure(app.UIFigure)
            
            depth_im = imread(fullfile(file_path, file_name));
    
            pc = depthImage2PC(depth_im);
            
            figure;
            pcshow(pc);
            
            xlabel('X')
            ylabel('Y')
            zlabel('Z')
        end

        % Button pushed function: ViewdepthimageforboundingboxButton
        function ViewdepthimageforboundingboxButtonPushed(app, event)
            [file_name, file_path] = uigetfile('*.png', 'Select a depth image');
            
            drawnow;
            figure(app.UIFigure)
            
            depth_im = imread(fullfile(file_path, file_name));
            
            plant_point = find_plant(depth_im, app.background_distance);

            segmented_im = segment_depth_im(depth_im, plant_point);
    
            pc = depthImage2PC(segmented_im);
            
            figure;
            pcshow(pc);
            
            xlabel('X')
            ylabel('Y')
            zlabel('Z')
        end

        % Button pushed function: ViewpointcloudButton
        function ViewpointcloudButtonPushed(app, event)
            [file_name, file_path] = uigetfile('*.ply', 'Select a point cloud');
            
            drawnow;
            figure(app.UIFigure)
            
            pc = pcread(fullfile(file_path, file_name));
            
            figure;
            pcshow(pc);
            
            xlabel('X')
            ylabel('Y')
            zlabel('Z')
        end

        % Value changed function: ImageHeightEditField
        function ImageHeightEditFieldValueChanged(app, event)
            value = app.ImageHeightEditField.Value;
            
            if strcmp(value, 'Image Height')
                app.im_height = value;
                
            elseif strcmp(value, 'Image Width')
                app.im_width = value;
            
            elseif strcmp(value, 'Number of views')
                app.im_no = value;
            end 
        end

        % Value changed function: TextparamEditField
        function TextparamEditFieldValueChanged(app, event)
            value = app.TextparamEditField.Value;
            
            app.plant_axis = value;
        end

        % Value changed function: booleanDropDown
        function booleanDropDownValueChanged(app, event)
            value = app.booleanDropDown.Value;
            
            if strcmp(value, 'true')
                value = true;
            else
                value = false;
            end
            
            if strcmp(value, 'Remove plane')
                app.do_remove_plane = value;
                
            elseif strcmp(value, 'Remove pot')
                app.do_remove_pot = value;
                
            elseif strcmp(value, 'Save point cloud')
                app.save_pc = value;
                
            elseif trcmp(value, 'Save measurements')
                app.save_measurements = value;
            end
        end

        % Value changed function: RightXboundEditField
        function RightXboundEditFieldValueChanged(app, event)
            value = app.RightXboundEditField.Value;
            
            app.bounding_box(2) = value;
        end

        % Value changed function: LeftXboundEditField
        function LeftXboundEditFieldValueChanged(app, event)
            value = app.LeftXboundEditField.Value;
            
            app.bounding_box(1) = value;
        end

        % Value changed function: BottomZboundEditField
        function BottomZboundEditFieldValueChanged(app, event)
            value = app.BottomZboundEditField.Value;
            
            app.bounding_box(3) = value;
        end

        % Value changed function: TopZboundEditField
        function TopZboundEditFieldValueChanged(app, event)
            value = app.TopZboundEditField.Value;
            
            app.bounding_box(4) = value;
        end

        % Button pushed function: AnalysesingleplantButton
        function AnalysesingleplantButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose the folder which contains the plant depth images');
            
            drawnow;
            figure(app.UIFigure)
            
            [pc, measurements] = analyse_plant(folder_path);
            
            figure;
            pcshow(pc);
            
            title('Point Cloud')
        end

        % Button pushed function: 
        % AnalysemultipleplantsovertimeButton
        function AnalysemultipleplantsovertimeButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose a top level folder which contains the folders for each timepoint');
            
            drawnow;
            figure(app.UIFigure)
            
            plant_ids = inputdlg('Enter comma-seperated IDs of the plants to analyse, e.g., ID_1,ID_2,ID_3');
            
            plant_ids = split(plant_ids, ',');
            
            for i = 1:numel(plant_ids)
                plant_id = plant_ids{i};
                try
                    analyse_over_time(folder_path, plant_id);
                catch
                    fprintf('Failed to analyse plant %s\n', plant_id);
                end
            end
            
            disp('\nComplete')
        end

        % Button pushed function: AnalyseallplantsovertimeButton
        function AnalyseallplantsovertimeButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose a top level folder which contains the folders for each timepoint');
            
            drawnow;
            figure(app.UIFigure)
            
            end_paths = get_end_paths(folder_path, {});
            
            for i = 1:numel(end_paths)
                split_path = split(end_paths{i}, '\');
                plant_id = split_path{end};
                try
                    analyse_over_time(folder_path, plant_id);
                catch
                    fprintf('Failed to analyse plant %s\n', plant_id);
                end
            end
            
            disp('Complete')
        end

        % Button pushed function: PlotmeasurementsButton
        function PlotmeasurementsButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose a top level folder which contains the folders for each timepoint');
            
            drawnow;
            figure(app.UIFigure)
            
            plant_ids = inputdlg('Enter comma-seperated IDs of the plants to analyse, e.g., ID_1,ID_2,ID_3');
            
            plant_ids = split(plant_ids, ',');
            
            for i = 1:numel(plant_ids)
                plant_id = plant_ids{i};
                
                try                
                    measurements_4D = get_4D_measurements(folder_path, plant_id);
                    fields = fieldnames(measurements_4D{1});
                    
                    for j = 1:numel(measurements_4D)                    
                        for k = 1:numel(fields)
                            all_measurements(i, j, k) = measurements_4D{j}.(fields{k});
                        end
                    end
                    
                catch
                    fprintf('No measurement files for plant %s\n', plant_id);
                end
            end
            
            dimensions = size(all_measurements);
            
            for k = 1:dimensions(3)
                figure;
                title(join(split(fields{k}, '_')))
                hold on
                
                for i = 1:dimensions(1)
                    plot(all_measurements(i, :, k));
                end
                
                legend
            end
        end

        % Button pushed function: PlotallmeasurementsButton
        function PlotallmeasurementsButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose a top level folder which contains the folders for each timepoint');
            
            drawnow;
            figure(app.UIFigure)
            
            end_paths = get_end_paths(folder_path, {});
            
            seen_ids = {};
            
            for i = 1:numel(end_paths)
                split_path = split(end_paths{i}, '\');
                plant_id = split_path{end};
                
                if ~any(strcmp(seen_ids, plant_id))
                    try                
                        measurements_4D = get_4D_measurements(folder_path, plant_id);
                        fields = fieldnames(measurements_4D{1});
                        
                        for j = 1:numel(measurements_4D)                    
                            for k = 1:numel(fields)
                                all_measurements(i, j, k) = measurements_4D{j}.(fields{k});
                            end
                        end
                        
                    catch
                        fprintf('No measurement files for plant %s\n', plant_id);
                    end
                    
                    seen_ids{end + 1} = plant_id;
                end
            end
            
            dimensions = size(all_measurements);
            
            for k = 1:dimensions(3)
                figure;
                title(join(split(fields{k}, '_')))
                hold on
                
                for i = 1:dimensions(1)
                    plot(all_measurements(i, :, k));
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CreateparameterfilesButton = uibutton(app.UIFigure, 'push');
            app.CreateparameterfilesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateparameterfilesButtonPushed, true);
            app.CreateparameterfilesButton.Text = 'Create parameter files';
            app.ParameterDropDownLabel = uilabel(app.UIFigure);
            app.ParameterDropDownLabel.Visible = 'off';
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.ParameterDropDown = uidropdown(app.UIFigure);
            app.ParameterDropDown.Items = {'Image Height', 'Image Width', 'Number of views', 'Vertical axis', 'Remove plane', 'Remove pot', 'Bounding box', 'Save point cloud', 'Save measurements'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Visible = 'off';
            app.ParameterDropDown.Value = 'Image Height';
            app.ImageHeightEditFieldLabel = uilabel(app.UIFigure);
            app.ImageHeightEditFieldLabel.Visible = 'off';
            app.ImageHeightEditFieldLabel.Text = 'Image Height';
            app.ImageHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageHeightEditField.Limits = [1 Inf];
            app.ImageHeightEditField.ValueChangedFcn = createCallbackFcn(app, @ImageHeightEditFieldValueChanged, true);
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditField.Value = 424;
            app.RightxboundEditFieldLabel = uilabel(app.UIFigure);
            app.RightxboundEditFieldLabel.Visible = 'off';
            app.RightxboundEditFieldLabel.Text = 'Right x bound';
            app.RightXboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightXboundEditField.ValueChangedFcn = createCallbackFcn(app, @RightXboundEditFieldValueChanged, true);
            app.RightXboundEditField.Visible = 'off';
            app.FrontyboundEditFieldLabel = uilabel(app.UIFigure);
            app.FrontyboundEditFieldLabel.Visible = 'off';
            app.FrontyboundEditFieldLabel.Text = 'Front y bound';
            app.BottomZboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.BottomZboundEditField.ValueChangedFcn = createCallbackFcn(app, @BottomZboundEditFieldValueChanged, true);
            app.BottomZboundEditField.Visible = 'off';
            app.BackyboundEditFieldLabel = uilabel(app.UIFigure);
            app.BackyboundEditFieldLabel.Visible = 'off';
            app.BackyboundEditFieldLabel.Text = 'Back y bound';
            app.TopZboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.TopZboundEditField.ValueChangedFcn = createCallbackFcn(app, @TopZboundEditFieldValueChanged, true);
            app.TopZboundEditField.Visible = 'off';
            app.TextparamEditFieldLabel = uilabel(app.UIFigure);
            app.TextparamEditFieldLabel.Visible = 'off';
            app.TextparamEditFieldLabel.Text = 'Text param';
            app.TextparamEditField = uieditfield(app.UIFigure, 'text');
            app.TextparamEditField.ValueChangedFcn = createCallbackFcn(app, @TextparamEditFieldValueChanged, true);
            app.TextparamEditField.Visible = 'off';
            app.GenerateparameterfilesButton = uibutton(app.UIFigure, 'push');
            app.GenerateparameterfilesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateparameterfilesButtonPushed, true);
            app.GenerateparameterfilesButton.Visible = 'off';
            app.GenerateparameterfilesButton.Text = 'Generate parameter files ';
            app.booleanDropDownLabel = uilabel(app.UIFigure);
            app.booleanDropDownLabel.Visible = 'off';
            app.booleanDropDownLabel.Text = 'boolean';
            app.booleanDropDown = uidropdown(app.UIFigure);
            app.booleanDropDown.Items = {'true', 'false'};
            app.booleanDropDown.ValueChangedFcn = createCallbackFcn(app, @booleanDropDownValueChanged, true);
            app.booleanDropDown.Visible = 'off';
            app.booleanDropDown.Value = 'true';
            app.LeftxboundEditFieldLabel = uilabel(app.UIFigure);
            app.LeftxboundEditFieldLabel.Visible = 'off';
            app.LeftxboundEditFieldLabel.Text = 'Left x bound';
            app.LeftXboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftXboundEditField.ValueChangedFcn = createCallbackFcn(app, @LeftXboundEditFieldValueChanged, true);
            app.LeftXboundEditField.Visible = 'off';
            app.ViewdepthimageforboundingboxButton = uibutton(app.UIFigure, 'push');
            app.ViewdepthimageforboundingboxButton.ButtonPushedFcn = createCallbackFcn(app, @ViewdepthimageforboundingboxButtonPushed, true);
            app.ViewdepthimageforboundingboxButton.Text = 'View depth image for bounding box';
            app.AnalysemultipleplantsovertimeButton = uibutton(app.UIFigure, 'push');
            app.AnalysemultipleplantsovertimeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysemultipleplantsovertimeButtonPushed, true);
            app.AnalysemultipleplantsovertimeButton.Text = 'Analyse multiple plants over time';
            app.AnalyseallplantsovertimeButton = uibutton(app.UIFigure, 'push');
            app.AnalyseallplantsovertimeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseallplantsovertimeButtonPushed, true);
            app.AnalyseallplantsovertimeButton.Text = 'Analyse all plants over time';
            app.PlotmeasurementsButton = uibutton(app.UIFigure, 'push');
            app.PlotmeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotmeasurementsButtonPushed, true);
            app.PlotmeasurementsButton.Text = 'Plot measurements';
            app.PlotallmeasurementsButton = uibutton(app.UIFigure, 'push');
            app.PlotallmeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotallmeasurementsButtonPushed, true);
            app.PlotallmeasurementsButton.Text = 'Plot all measurements';
            app.AnalysesingleplantButton = uibutton(app.UIFigure, 'push');
            app.AnalysesingleplantButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysesingleplantButtonPushed, true);
            app.AnalysesingleplantButton.Text = 'Analyse single plant';
            app.ViewpointcloudButton = uibutton(app.UIFigure, 'push');
            app.ViewpointcloudButton.ButtonPushedFcn = createCallbackFcn(app, @ViewpointcloudButtonPushed, true);
            app.ViewpointcloudButton.Text = 'View point cloud';
            app.ViewfulldepthimageButton = uibutton(app.UIFigure, 'push');
            app.ViewfulldepthimageButton.ButtonPushedFcn = createCallbackFcn(app, @ViewfulldepthimageButtonPushed, true);
            app.ViewfulldepthimageButton.Text = 'View full depth image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plant_analysis_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end